classdef Blue_energy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CapacityFactorEditField         matlab.ui.control.NumericEditField
        CapacityFactorEditFieldLabel    matlab.ui.control.Label
        EnergyPowersurfacegradientGWEditFieldLabel  matlab.ui.control.Label
        EnergyPowervericalgradientGWEditFieldLabel  matlab.ui.control.Label
        DayswithfulloperationalconditionsEditField  matlab.ui.control.NumericEditField
        DayswithfulloperationalconditionsEditFieldLabel  matlab.ui.control.Label
        DayswithreducesoperationalconditionsEditField  matlab.ui.control.NumericEditField
        DayswithreducesoperationalconditionsEditFieldLabel  matlab.ui.control.Label
        NoextractiondaysEditField       matlab.ui.control.NumericEditField
        NoextractiondaysEditFieldLabel  matlab.ui.control.Label
        ExtractionFactorEditField       matlab.ui.control.NumericEditField
        ExtractionFactorLabel           matlab.ui.control.Label
        EnergyPowersurfacegradientGWEditField  matlab.ui.control.NumericEditField
        EnergyPowervericalgradientGWEditField  matlab.ui.control.NumericEditField
        Slider                          matlab.ui.control.Slider
        DesingDischargeButton           matlab.ui.control.StateButton
        LocationPanel                   matlab.ui.container.Panel
        EnviromentalRestrictionDropDown  matlab.ui.control.DropDown
        EnviromentalRestrictionDropDownLabel  matlab.ui.control.Label
        RiverDropDown                   matlab.ui.control.DropDown
        RiverDropDownLabel              matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DQ10
        CF10
        gx
        riverselec=1;
        enviromental=0.3;
        percentaje=0;
        DQ30
        CF30
    shutdowndays10=nan(1078,100,12);
    reducedtimedays10=nan(1078,100,12);
    fulltimedays10=nan(1078,100,12);
    QTotal10=zeros(1078,100,12);
    shutdowndays=nan(1078,100,12);
    reducedtimedays=nan(1078,100,12);
    fulltimedays=nan(1078,100,12);
    QTotal=zeros(1078,100,12);
    temp
    temp10
    DesingD
    linedq
    linedq2
    lineH
    lineH2
    pathgreen
    pathyellow
    pathred
    temp2
    temp102
    meandischarge2
    end
    
    methods (Access = private)
        
        function results = Plotdischarge(app)


app.DayswithfulloperationalconditionsEditFieldLabel.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.DayswithreducesoperationalconditionsEditFieldLabel.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.NoextractiondaysEditFieldLabel.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.CapacityFactorEditFieldLabel.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.EnergyPowersurfacegradientGWEditFieldLabel.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.EnergyPowervericalgradientGWEditFieldLabel.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.RiverDropDownLabel.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.EnviromentalRestrictionDropDownLabel.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.Slider.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.ExtractionFactorLabel.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.DesingDischargeButton.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.DayswithfulloperationalconditionsEditField.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.DayswithreducesoperationalconditionsEditField.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.NoextractiondaysEditField.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.CapacityFactorEditField.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.EnergyPowersurfacegradientGWEditField.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.EnergyPowervericalgradientGWEditField.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.RiverDropDown.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.EnviromentalRestrictionDropDown.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.Slider.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.ExtractionFactorEditField.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.DesingDischargeButton.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
    load('dataui.mat','meandischarge')
    load('dataui.mat','energyden10horizontal')
    load('dataui.mat','energyden10vertical')
    load('dataui.mat','rivernames')
    load('dataui.mat','latriverm','lonriverm')


                k=app.riverselec;
    cla(app.UIAxes,'reset')
    set(app.UIAxes,'backgroundcolor','w')
    
                      hold(app.UIAxes,'on')
        app.pathgreen=patch([linspace(1,12,334), fliplr(linspace(1,12,334))],[app.meandischarge2(:,k);flipud(app.meandischarge2(:,k))],[85/255 113/255 83/255],'linestyle','none','Parent',app.UIAxes);
        app.pathyellow=patch([linspace(1,12,334),fliplr(linspace(1,12,334))],[app.meandischarge2(:,k);flipud(app.meandischarge2(:,k))],[230/255 229/255 163/255],'linestyle','none','Parent',app.UIAxes); 
        app.pathred=patch([linspace(1,12,334) fliplr(linspace(1,12,334))],[app.meandischarge2(:,k);flipud(app.meandischarge2(:,k))],[153/255 0/255 0/255],'linestyle','none','Parent',app.UIAxes);


            plot(1:12,linspace(nanmean(meandischarge(:,app.riverselec).*app.enviromental),nanmean(meandischarge(:,app.riverselec).*app.enviromental),12),'k','linewidth',4,'linestyle','--','parent',app.UIAxes)
            app.lineH=plot(1:12,(meandischarge(:,app.riverselec))-nanmean(meandischarge(:,app.riverselec)).*0./100,'color','red','linewidth',4,'parent',app.UIAxes);     
            plot(1:12,meandischarge(:,app.riverselec),'b','linewidth',4,'Parent',app.UIAxes)
            app.UIAxes.XTick=[1:12];
            app.UIAxes.XLim=[1 12];
            app.UIAxes.YLim=[0 nanmax(meandischarge(:,app.riverselec)).*1.2];
            app.UIAxes.XTickLabel={'JAN','FEB','MAR','APR','MAY','JUN','JUL','AGO','SEP','OCT','NOV','DEC'};
            app.UIAxes.YLabel.String='Discharge (m^3/s)';
            app.UIAxes.XLabel.String='';
            app.UIAxes.XGrid='on';
            app.UIAxes.YGrid='on';
            set(app.UIAxes,'fontsize',max(get(app.UIFigure,'Position'))*25/1920)
            title(app.UIAxes,rivernames(app.riverselec))
  
            %      
            cla(app.gx,'reset')
        
            geoscatter(app.gx,latriverm(app.riverselec),lonriverm(app.riverselec),'MarkerFaceColor','red')
            geolimits(app.gx,[latriverm(app.riverselec)-3 latriverm(app.riverselec)+3],[lonriverm(app.riverselec)-3 lonriverm(app.riverselec)+3]);
            app.gx.LatitudeLabel.String='Latitude (°)';
            app.gx.LongitudeLabel.String='Longitude (°)';
            geobasemap(app.gx,'colorterrain');
            %           


        end
        
        function results = operation(app)
        

                   if app.enviromental==0.3
                cla(app.UIAxes2,'reset')
set(app.UIAxes2,'backgroundcolor','w')
                yyaxis(app.UIAxes2,"left")
            plot(1:100,app.fulltimedays(app.riverselec,:),'b','linestyle','-','parent',app.UIAxes2)
            hold(app.UIAxes2,'on')
            plot(1:100,app.reducedtimedays(app.riverselec,:),'k','linestyle','-','parent',app.UIAxes2)
            plot(1:100,app.shutdowndays(app.riverselec,:),'r','linestyle','-','parent',app.UIAxes2)
            app.UIAxes2.XLabel.String='Extraction factor (% mean discharge)';
            app.UIAxes2.YLabel.String='Days in a year';
            app.UIAxes2.XLim=[0 100];
            app.UIAxes2.YLim=[0 365];
            app.UIAxes2.XGrid='on';
            app.UIAxes2.YGrid='on';
            set(app.UIAxes2,'Ycolor','black')
            yyaxis(app.UIAxes2,'right')
            plot(1:100,app.CF30(app.riverselec,:),'LineStyle','--','color','black','parent',app.UIAxes2)
            set(app.UIAxes2,'fontsize',max(get(app.UIFigure,'Position'))*20/1920)
            set(app.UIAxes2,'Ycolor','black')
            app.UIAxes2.YLim=[0 1];
            app.UIAxes2.YLabel.String='Capacity Factor';
            title(app.UIAxes2,'')
            end
            
            if app.enviromental==0.1
                    cla(app.UIAxes2,'reset')
set(app.UIAxes2,'backgroundcolor','w')
                yyaxis(app.UIAxes2,"left")
            plot(1:100,app.fulltimedays10(app.riverselec,:),'b','linestyle','-','parent',app.UIAxes2)
            hold(app.UIAxes2,'on')
            plot(1:100,app.reducedtimedays10(app.riverselec,:),'k','linestyle','-','parent',app.UIAxes2)
            plot(1:100,app.shutdowndays10(app.riverselec,:),'r','linestyle','-','parent',app.UIAxes2)
            app.UIAxes2.XLabel.String='Extraction factor(% mean discharge)';
            app.UIAxes2.YLabel.String='Days in a year';
            app.UIAxes2.XLim=[1 100];
            app.UIAxes2.YLim=[0 365];
            app.UIAxes2.XGrid='on';
            app.UIAxes2.YGrid='on';
            set(app.UIAxes2,'Ycolor','black')
            yyaxis(app.UIAxes2,'right')
            plot(1:100,app.CF10(app.riverselec,:),'LineStyle','--','color','black','parent',app.UIAxes2)
            set(app.UIAxes2,'fontsize',max(get(app.UIFigure,'Position'))*20/1920)
            set(app.UIAxes2,'Ycolor','black')
            app.UIAxes2.YLim=[0 1];
            app.UIAxes2.YLabel.String='Capacity Factor';
            title(app.UIAxes2,'')
            
            end
                    app.lineH2=plot(linspace(0,0,10),0:9,'k','linestyle','--','linewidth',2.5,'Parent',app.UIAxes2);

        end
        
        function results = DesingDactive(app)
            load('dataui.mat','meandischarge')
            k=app.riverselec;
            if app.enviromental==0.3
                app.linedq= plot(1:12,meandischarge(:,k)-squeeze(app.temp(k,app.DQ30(k),:)),'g','linestyle','--','linewidth',2.5,'Parent',app.UIAxes);  
                app.linedq2=plot(linspace(app.DQ30(k),app.DQ30(k),10),0:9,'g','linestyle','--','linewidth',2.5,'Parent',app.UIAxes2);

            end
            if app.enviromental==0.1
                app.linedq2=plot(linspace(app.DQ10(k),app.DQ10(k),10),0:9,'g','linestyle','--','linewidth',2.5,'Parent',app.UIAxes2);
                app.linedq= plot(1:12,meandischarge(:,k)-squeeze(app.temp10(k,app.DQ10(k),:)),'g','linestyle','--','linewidth',2.5,'Parent',app.UIAxes);  
            end
            
        end
        
        function results = DesingDoff(app)
            app.linedq.YData=[];
            app.linedq2.YData=[];

      
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            
load('dataui.mat','meandischarge')
load('dataui.mat','energyden10horizontal')
load('dataui.mat','energyden10vertical')
load('dataui.mat','rivernames')
load('dataui.mat','latriverm','lonriverm')

%%
set(app.UIAxes,'backgroundcolor','w')
set(app.UIAxes2,'backgroundcolor','w')


app.gx=geoaxes(app.LocationPanel);
geobasemap(app.gx,'colorterrain');
app.RiverDropDown.Items=rivernames;
app.RiverDropDown.ItemsData=[1:1078]';
app.EnviromentalRestrictionDropDown.Items=({'30%','10%'});
app.Slider.MajorTicks=[0:10:100]';
app.Slider.MajorTickLabels={'0';'10';'20';'30';'40';'50';'60';'70';'80';'90';'100'};
app.DayswithfulloperationalconditionsEditFieldLabel.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.DayswithreducesoperationalconditionsEditFieldLabel.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.NoextractiondaysEditFieldLabel.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.CapacityFactorEditFieldLabel.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.EnergyPowersurfacegradientGWEditFieldLabel.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.EnergyPowervericalgradientGWEditFieldLabel.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.RiverDropDownLabel.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.EnviromentalRestrictionDropDownLabel.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.Slider.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.ExtractionFactorLabel.FontSize=max(get(app.UIFigure,'Position'))*20/1920;
app.DesingDischargeButton.FontSize=max(get(app.UIFigure,'Position'))*20/1920;

%%

anualmean=nanmean(meandischarge,1);
DiM=[31,28,31,30,31,30,31,31,30,31,30,31];

%%30
for i=1:size(anualmean,2)
    for j=1:100
        desingQ=anualmean(i)*(j/100);
        control(i,j,1:12)=meandischarge(:,i);
        app.temp(i,j,1:12)=desingQ;
    end
end
reducedQ=nan(1078,100,12);
shutdown=nan(1078,100,12);
for i=1:size(anualmean,2)   
    for j=1:100
        for k=1:12
             if control(i,j,k)<anualmean(i)*0.3
                shutdown(i,j,k)=1;
             else
            if (control(i,j,k)-app.temp(i,j,k))>=anualmean(i)*0.3
        fulltime30(i,j,k)=1;
            else
        fulltime30(i,j,k)=0;
        reducedQ(i,j,k)=(control(i,j,k)-anualmean(i)*0.3);
            end
             end
        end
    end
end

for i=1:size(anualmean,2)   
    for j=1:100
        for k=1:12
            if shutdown(i,j,k)==1
                app.shutdowndays(i,j,k)=DiM(k);
            else
        if fulltime30(i,j,k)==1
            app.QTotal(i,j,k)=app.temp(i,j,k);
            app.fulltimedays(i,j,k)=DiM(k);
            app.reducedtimedays(i,j,k)=nan;
        else
            app.QTotal(i,j,k)=reducedQ(i,j,k);
            app.fulltimedays(i,j,k)=nan;
            app.reducedtimedays(i,j,k)=DiM(k);
        end
            end
        end
        end
end

    app.QTotal=sum(app.QTotal,3);
    app.fulltimedays=nansum(app.fulltimedays,3);
    app.reducedtimedays=nansum(app.reducedtimedays,3);
    app.shutdowndays=nansum(app.shutdowndays,3);
for i=1:max(size(app.QTotal))  
    Qnormal(i,:)=app.QTotal(i,:)./max(app.QTotal(i,:));
end
Qideal=sum(app.temp,3);
Qreal=app.QTotal;
app.CF30=Qreal./Qideal;
for i=1:size(app.reducedtimedays,1)
    for j=1:100   
    if abs(app.reducedtimedays(i,j)-app.shutdowndays(i,j))<= 5
    app.DQ30(i)=j;
    end
    end
end
for i=1:size(app.reducedtimedays,1)
    for j=1:100   
    if abs(app.reducedtimedays(i,j)-app.shutdowndays(i,j))<= 5
    app.DQ30(i)=j;
    end
    end
    if app.DQ30(i)==0
        for j=1:100
            if app.reducedtimedays(i,j)<=90
            app.DQ30(i)=j;
            end
        end
    end
end
%%
%%10
for i=1:size(anualmean,2)
    for j=1:100
        desingQ10=anualmean(i)*(j/100);
        control10(i,j,1:12)=meandischarge(:,i);
        app.temp10(i,j,1:12)=desingQ10;
    end
end

reducedQ10=nan(1078,100,12);
shutdown10=nan(1078,100,12);
for i=1:size(anualmean,2)   
    for j=1:100
        for k=1:12
             if control10(i,j,k)<anualmean(i)*0.1
                shutdown10(i,j,k)=1;
             else
            if (control10(i,j,k)-app.temp10(i,j,k))>=anualmean(i)*0.1
        fulltime3010(i,j,k)=1;
            else
        fulltime3010(i,j,k)=0;
        reducedQ10(i,j,k)=(control10(i,j,k)-anualmean(i)*0.1);
            end
             end
        end
    end
end

for i=1:size(anualmean,2)   
    for j=1:100
        for k=1:12
            if shutdown10(i,j,k)==1
                app.shutdowndays10(i,j,k)=DiM(k);
            else
        if fulltime3010(i,j,k)==1
            app.QTotal10(i,j,k)=app.temp10(i,j,k);
            app.fulltimedays10(i,j,k)=DiM(k);
            app.reducedtimedays10(i,j,k)=nan;
        else
            app.QTotal10(i,j,k)=reducedQ10(i,j,k);
            app.fulltimedays10(i,j,k)=nan;
            app.reducedtimedays10(i,j,k)=DiM(k);
        end
            end
        end
        end
end


    app.QTotal10=sum(app.QTotal10,3);
    app.fulltimedays10=nansum(app.fulltimedays10,3);
    app.reducedtimedays10=nansum(app.reducedtimedays10,3);
    app.shutdowndays10=nansum(app.shutdowndays10,3);
for i=1:max(size(app.QTotal10))  
    Qnormal10(i,:)=app.QTotal10(i,:)./max(app.QTotal10(i,:));
end
Qideal10=sum(app.temp10,3);
Qreal10=app.QTotal10;
app.CF10=Qreal10./Qideal10;
for i=1:size(app.reducedtimedays10,1)
    for j=1:100   
    if abs(app.reducedtimedays10(i,j)-app.shutdowndays10(i,j))<= 5
    app.DQ10(i)=j;
    end
    end
end
for i=1:size(app.reducedtimedays10,1)
    for j=1:100      
    if abs(app.reducedtimedays10(i,j)-app.shutdowndays10(i,j))<= 5
   app.DQ10(i)=j;
    end
    end
    if app.DQ10(i)==0
        for j=1:100
            if app.reducedtimedays10(i,j)<=90
            app.DQ10(i)=j;
            end
        end
    end
end


DiM=[31,28,31,30,31,30,31,31,30,31,30,31];
DiM2=[1,31,59,90,120,151,181,212,243,273,304,334];
for i=1:size(meandischarge,2)
    h=0;
    for j=1:size(meandischarge,1)-1
        app.meandischarge2(DiM2(j)+h:DiM2(j+1),i)=linspace(meandischarge(j,i),meandischarge(j+1,i),DiM(j));
        for p=1:100
        app.temp2(i,p,DiM2(j)+h:DiM2(j+1))=linspace(app.temp(i,p,j),app.temp(i,p,j+1),DiM(j));
        app.temp102(i,p,DiM2(j)+h:DiM2(j+1))=linspace(app.temp10(i,p,j),app.temp10(i,p,j+1),DiM(j));
        end
        h=1;
    end  
end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.ExtractionFactorEditField.Value=round(value);
            app.percentaje=round(value);

        end

        % Value changed function: RiverDropDown
        function RiverDropDownValueChanged(app, event)
            app.riverselec = app.RiverDropDown.Value;
            display(app.riverselec)
            Plotdischarge(app)
            operation(app)

        end

        % Value changed function: EnviromentalRestrictionDropDown
        function EnviromentalRestrictionDropDownValueChanged(app, event)
            value = app.EnviromentalRestrictionDropDown.Value;
            if value=='30%'
                app.enviromental=0.3;
            end
            if value=='10%'
                app.enviromental=0.1;
            end
            Plotdischarge(app)
            operation(app)
        end

        % Value changed function: DesingDischargeButton
        function DesingDischargeButtonValueChanged(app, event)
            value = app.DesingDischargeButton.Value;
            app.DesingD=value
            if value==1
                DesingDactive(app)
            end
            if value==0
                DesingDoff(app)
            end
          
            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            load('dataui.mat','meandischarge')
            load('dataui.mat','energyden10horizontal')
            load('dataui.mat','energyden10vertical')


            k=app.riverselec;
            idx=fliplr(335:668);
            if changingValue < 1 
                changingValue=1;
            end
            anualmean=nanmean(meandischarge,1);
            redpathYData=[app.meandischarge2(:,k);flipud(app.meandischarge2(:,k))];
          if any(redpathYData<=anualmean(k)*app.enviromental)
          checking=find(redpathYData<=anualmean(k)*app.enviromental);
          redpathYData(checking(checking>334))=anualmean(k)*app.enviromental;
          end
          app.pathred.YData=redpathYData;
          %%
            app.lineH2.XData=linspace(round(changingValue),round(changingValue),10);
            linehydata=(meandischarge(:,k)-squeeze(app.temp(k,round(changingValue),:)));
            linehydata(linehydata<nanmean(meandischarge(:,k).*app.enviromental))=nanmean(meandischarge(:,k).*app.enviromental);
            app.lineH.YData= linehydata;
          %%
          if app.enviromental == 0.3
          greenpathYData=[(app.meandischarge2(:,k)-squeeze(app.temp2(k,round(changingValue),:)));flipud(app.meandischarge2(:,k))];
          greenpathYData(greenpathYData<anualmean(k)*app.enviromental)=anualmean(k)*app.enviromental;
          yellowpathYData=[(app.meandischarge2(:,k)-squeeze(app.temp2(k,round(changingValue),:)));flipud(app.meandischarge2(:,k)-squeeze(app.temp2(k,round(changingValue),:)))];
          for checking2=1:334
              if yellowpathYData(checking2)<=anualmean(k)*app.enviromental
                  yellowpathYData(checking2)=anualmean(k)*app.enviromental;
                  yellowpathYData(idx(checking2))=app.meandischarge2(checking2,k);
              end
          end
          app.pathyellow.YData=yellowpathYData;
          app.pathgreen.YData=greenpathYData;
          end
                 if app.enviromental == 0.1
          greenpathYData=[(app.meandischarge2(:,k)-squeeze(app.temp102(k,round(changingValue),:)));flipud(app.meandischarge2(:,k))];
          greenpathYData(greenpathYData<anualmean(k)*app.enviromental)=anualmean(k)*app.enviromental;
          yellowpathYData=[(app.meandischarge2(:,k)-squeeze(app.temp102(k,round(changingValue),:)));flipud(app.meandischarge2(:,k)-squeeze(app.temp102(k,round(changingValue),:)))];
          for checking2=1:334
              if yellowpathYData(checking2)<=anualmean(k)*app.enviromental
                  yellowpathYData(checking2)=anualmean(k)*app.enviromental;
                  yellowpathYData(idx(checking2))=app.meandischarge2(checking2,k);
              end
          end
          app.pathyellow.YData=yellowpathYData;
          app.pathgreen.YData=greenpathYData;
                 end
                 changingValue=round(changingValue);
        app.ExtractionFactorEditField.Value=changingValue;
        if app.enviromental==0.3
        app.DayswithfulloperationalconditionsEditField.Value=app.fulltimedays(k,changingValue);
        app.DayswithreducesoperationalconditionsEditField.Value=app.reducedtimedays(k,changingValue);
        app.NoextractiondaysEditField.Value=app.shutdowndays(k,changingValue);
        app.CapacityFactorEditField.Value=app.CF30(k,changingValue).*100;
        app.EnergyPowersurfacegradientGWEditField.Value=nansum(energyden10horizontal(k,:).*squeeze(app.QTotal(k,changingValue,:))')./1000;
        app.EnergyPowervericalgradientGWEditField.Value=nansum(energyden10vertical(k,:).*squeeze(app.QTotal(k,changingValue,:))')./1000;
        end
        if app.enviromental==0.1
        app.DayswithfulloperationalconditionsEditField.Value=app.fulltimedays10(k,changingValue);
        app.DayswithreducesoperationalconditionsEditField.Value=app.reducedtimedays10(k,changingValue);
        app.NoextractiondaysEditField.Value=app.shutdowndays10(k,changingValue);
        app.CapacityFactorEditField.Value=app.CF10(k,changingValue).*100;
        app.EnergyPowersurfacegradientGWEditField.Value=nansum(energyden10horizontal(k,:).*squeeze(app.QTotal10(k,changingValue,:))')./1000;
        app.EnergyPowervericalgradientGWEditField.Value=nansum(energyden10vertical(k,:).*squeeze(app.QTotal10(k,changingValue,:))')./1000;
        end
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.RiverDropDownLabel = uilabel(app.UIFigure);
            app.RiverDropDownLabel.Text = 'River';
            app.RiverDropDown = uidropdown(app.UIFigure);
            app.RiverDropDown.ValueChangedFcn = createCallbackFcn(app, @RiverDropDownValueChanged, true);
            app.EnviromentalRestrictionDropDownLabel = uilabel(app.UIFigure);
            app.EnviromentalRestrictionDropDownLabel.Text = 'Enviromental Restriction';
            app.EnviromentalRestrictionDropDown = uidropdown(app.UIFigure);
            app.EnviromentalRestrictionDropDown.ValueChangedFcn = createCallbackFcn(app, @EnviromentalRestrictionDropDownValueChanged, true);
            app.LocationPanel = uipanel(app.UIFigure);
            app.LocationPanel.Title = 'Location';
            app.DesingDischargeButton = uibutton(app.UIFigure, 'state');
            app.DesingDischargeButton.ValueChangedFcn = createCallbackFcn(app, @DesingDischargeButtonValueChanged, true);
            app.DesingDischargeButton.Text = 'Desing Discharge';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.EnergyPowervericalgradientGWEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnergyPowersurfacegradientGWEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExtractionFactorLabel = uilabel(app.UIFigure);
            app.ExtractionFactorLabel.Text = 'Extraction Factor (%)';
            app.ExtractionFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoextractiondaysEditFieldLabel = uilabel(app.UIFigure);
            app.NoextractiondaysEditFieldLabel.Text = 'No extraction days';
            app.NoextractiondaysEditField = uieditfield(app.UIFigure, 'numeric');
            app.DayswithreducesoperationalconditionsEditFieldLabel = uilabel(app.UIFigure);
            app.DayswithreducesoperationalconditionsEditFieldLabel.Text = 'Days with reduces operational conditions';
            app.DayswithreducesoperationalconditionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DayswithfulloperationalconditionsEditFieldLabel = uilabel(app.UIFigure);
            app.DayswithfulloperationalconditionsEditFieldLabel.Text = 'Days with full operational conditions';
            app.DayswithfulloperationalconditionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnergyPowervericalgradientGWEditFieldLabel = uilabel(app.UIFigure);
            app.EnergyPowervericalgradientGWEditFieldLabel.Text = 'Energy Power verical  gradient (GW)';
            app.EnergyPowersurfacegradientGWEditFieldLabel = uilabel(app.UIFigure);
            app.EnergyPowersurfacegradientGWEditFieldLabel.Text = 'Energy Power surface gradient (GW)';
            app.CapacityFactorEditFieldLabel = uilabel(app.UIFigure);
            app.CapacityFactorEditFieldLabel.Text = 'Capacity Factor (%)';
            app.CapacityFactorEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Blue_energy(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end